classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        path                            matlab.ui.control.EditField
        PodajciekdoplikuEditFieldLabel  matlab.ui.control.Label
        GotoweLamp                      matlab.ui.control.Lamp
        GotoweLampLabel                 matlab.ui.control.Label
        WynikidlawybranegoczonuPanel    matlab.ui.container.Panel
        plotElement                     matlab.ui.control.Button
        chooseElement                   matlab.ui.control.DropDown
        WybierzczonDropDownLabel        matlab.ui.control.Label
        WynikidlawybranegopunktuPanel   matlab.ui.container.Panel
        ZktrymczonemjestzwizanyLabel    matlab.ui.control.Label
        chooseElement_2                 matlab.ui.control.DropDown
        WybierzukadSwitchLabel          matlab.ui.control.Label
        cs                              matlab.ui.control.ToggleSwitch
        PodajwsprzdnypunktuEditFieldLabel  matlab.ui.control.Label
        y_cord                          matlab.ui.control.NumericEditField
        PodajwsprzdnxpunktuEditFieldLabel  matlab.ui.control.Label
        x_cord                          matlab.ui.control.NumericEditField
        plotPoint                       matlab.ui.control.Button
        solveButton                     matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        time % Description
        results
        howManyElements
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: path
        function pathValueChanged(app, event)
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            app.UIFigure
        end

        % Callback function
        function ButtonPushed(app, event)
            
        end

        % Value changing function: path
        function pathValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: chooseElement_2
        function chooseElement_2ValueChanged(app, event)
            value = app.chooseElement_2.Value;
            
        end

        % Button pushed function: plotElement
        function plotElementPushed2(app, event)
            plot_result(str2double(app.chooseElement.Value),app.results,app.time);
        end

        % Button pushed function: plotPoint
        function plotPointPushed(app, event)
            point = [app.x_cord.Value; app.y_cord.Value];
            element_no = str2double(app.chooseElement_2.Value);
            if app.cs.Value == 'AUW'
                point = point - app.results.q(3*element_no-2:3*element_no-1,1);
            end
            plot_point(point,element_no,app.results,app.time);
        end

        % Button pushed function: solveButton
        function solveButtonPushed(app, event)
            app.GotoweLamp.Color = [1 0 0];
            path = app.path.Value;
            main;
            app.time = time;
            app.results = results;
            app.chooseElement.Items = string(1:width(elements)-1);
            app.chooseElement_2.Items = string(1:width(elements)-1);
            app.GotoweLamp.Color = [0 1 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.path = uieditfield(app.GridLayout, 'text');
            app.path.ValueChangedFcn = createCallbackFcn(app, @pathValueChanged, true);
            app.path.ValueChangingFcn = createCallbackFcn(app, @pathValueChanging, true);
            app.PodajciekdoplikuEditFieldLabel = uilabel(app.GridLayout);
            app.PodajciekdoplikuEditFieldLabel.Text = 'Podaj ścieżkę do pliku';
            app.GotoweLamp = uilamp(app.GridLayout);
            app.GotoweLampLabel = uilabel(app.GridLayout);
            app.GotoweLampLabel.Text = 'Gotowe';
            app.WynikidlawybranegoczonuPanel = uipanel(app.GridLayout);
            app.WynikidlawybranegoczonuPanel.Title = 'Wyniki dla wybranego członu';
            app.plotElement = uibutton(app.WynikidlawybranegoczonuPanel, 'push');
            app.plotElement.ButtonPushedFcn = createCallbackFcn(app, @plotElementPushed2, true);
            app.plotElement.Text = 'Wykresy dla wybranego członu';
            app.chooseElement = uidropdown(app.WynikidlawybranegoczonuPanel);
            app.WybierzczonDropDownLabel = uilabel(app.WynikidlawybranegoczonuPanel);
            app.WybierzczonDropDownLabel.Text = 'Wybierz człon';
            app.WynikidlawybranegopunktuPanel = uipanel(app.GridLayout);
            app.WynikidlawybranegopunktuPanel.Title = 'Wyniki dla wybranego punktu';
            app.ZktrymczonemjestzwizanyLabel = uilabel(app.WynikidlawybranegopunktuPanel);
            app.ZktrymczonemjestzwizanyLabel.Text = 'Z którym członem jest związany?';
            app.chooseElement_2 = uidropdown(app.WynikidlawybranegopunktuPanel);
            app.chooseElement_2.ValueChangedFcn = createCallbackFcn(app, @chooseElement_2ValueChanged, true);
            app.WybierzukadSwitchLabel = uilabel(app.WynikidlawybranegopunktuPanel);
            app.WybierzukadSwitchLabel.Text = 'Wybierz układ';
            app.cs = uiswitch(app.WynikidlawybranegopunktuPanel, 'toggle');
            app.cs.Items = {'LUW', 'AUW'};
            app.cs.Value = 'LUW';
            app.PodajwsprzdnypunktuEditFieldLabel = uilabel(app.WynikidlawybranegopunktuPanel);
            app.PodajwsprzdnypunktuEditFieldLabel.Text = 'Podaj współrzędną y punktu';
            app.y_cord = uieditfield(app.WynikidlawybranegopunktuPanel, 'numeric');
            app.PodajwsprzdnxpunktuEditFieldLabel = uilabel(app.WynikidlawybranegopunktuPanel);
            app.PodajwsprzdnxpunktuEditFieldLabel.Text = 'Podaj współrzędną x punktu';
            app.x_cord = uieditfield(app.WynikidlawybranegopunktuPanel, 'numeric');
            app.x_cord.Value = 1;
            app.plotPoint = uibutton(app.WynikidlawybranegopunktuPanel, 'push');
            app.plotPoint.ButtonPushedFcn = createCallbackFcn(app, @plotPointPushed, true);
            app.plotPoint.Text = 'Wykresy dla wybranego punktu';
            app.solveButton = uibutton(app.GridLayout, 'push');
            app.solveButton.ButtonPushedFcn = createCallbackFcn(app, @solveButtonPushed, true);
            app.solveButton.Text = 'Rozwiąż';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.GotoweLamp.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end