classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PodajciekdoplikuEditFieldLabel  matlab.ui.control.Label
        path                            matlab.ui.control.EditField
        LampLabel                       matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        Button                          matlab.ui.control.Button
        WybierzczonDropDownLabel        matlab.ui.control.Label
        choseElement                    matlab.ui.control.DropDown
        ContextMenu                     matlab.ui.container.ContextMenu
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        time % Description
        results
        howManyElements
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: path
        function pathValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changed function: path
        function pathValueChanged(app, event)
            path = app.path.Value;
            main;
            app.time = time;
            app.results = results;
            app.choseElement.Items = string(1:width(elements)-1);
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            app.UIFigure
        end

        % Callback function
        function ButtonPushed(app, event)
            plot_result(1)
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            plot_result(1,app.results,app.time);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PodajciekdoplikuEditFieldLabel = uilabel(app.UIFigure);
            app.PodajciekdoplikuEditFieldLabel.Text = 'Podaj ścieżkę do pliku';
            app.path = uieditfield(app.UIFigure, 'text');
            app.path.ValueChangedFcn = createCallbackFcn(app, @pathValueChanged, true);
            app.path.ValueChangingFcn = createCallbackFcn(app, @pathValueChanging, true);
            app.path.Value = 'kinematyka5.xls';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.WybierzczonDropDownLabel = uilabel(app.UIFigure);
            app.WybierzczonDropDownLabel.Text = 'Wybierz człon';
            app.choseElement = uidropdown(app.UIFigure);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.Lamp.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end